classdef guiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        LeftArmArm1Panel           matlab.ui.container.Panel
        ArmStateButtonGroup        matlab.ui.container.ButtonGroup
        ForwardButton              matlab.ui.control.ToggleButton
        StopButton                 matlab.ui.control.ToggleButton
        BackwardButton             matlab.ui.control.ToggleButton
        GripperStateButtonGroup_1  matlab.ui.container.ButtonGroup
        CloseButton_1              matlab.ui.control.ToggleButton
        OpenButton_1               matlab.ui.control.ToggleButton
        Spinner_1                  matlab.ui.control.Spinner
        ArmPositionSlider_1        matlab.ui.control.Slider
        ArmPositionSliderLabel     matlab.ui.control.Label
        RightArmArm2Panel          matlab.ui.container.Panel
        ArmStateButtonGroup_2      matlab.ui.container.ButtonGroup
        ForwardButton_2            matlab.ui.control.ToggleButton
        StopButton_2               matlab.ui.control.ToggleButton
        BackwardButton_2           matlab.ui.control.ToggleButton
        GripperStateButtonGroup_2  matlab.ui.container.ButtonGroup
        CloseButton_2              matlab.ui.control.ToggleButton
        OpenButton_2               matlab.ui.control.ToggleButton
        Spinner_2                  matlab.ui.control.Spinner
        ArmPositionSlider_2        matlab.ui.control.Slider
        ArmPositionSlider_2Label   matlab.ui.control.Label
        CenterArmArm3Panel         matlab.ui.container.Panel
        ArmPositionSlider_3        matlab.ui.control.Slider
        ArmPositionSlider_3Label   matlab.ui.control.Label
        Spinner_3                  matlab.ui.control.Spinner
        ArmStateButtonGroup_3      matlab.ui.container.ButtonGroup
        ForwardButton_3            matlab.ui.control.ToggleButton
        StopButton_3               matlab.ui.control.ToggleButton
        BackwardButton_3           matlab.ui.control.ToggleButton
        GripperStateButtonGroup_3  matlab.ui.container.ButtonGroup
        CloseButton_3              matlab.ui.control.ToggleButton
        OpenButton_3               matlab.ui.control.ToggleButton
        Arm4Panel                  matlab.ui.container.Panel
        GripperStateButtonGroup_4  matlab.ui.container.ButtonGroup
        CloseButton_4              matlab.ui.control.ToggleButton
        OpenButton_4               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        ipAddress = "192.168.4.1"; % IP Address to send info
        webDestination = strcat("http://192.168.4.1", "/receive");
        webOptions = weboptions("Timeout", 5);
    end
    
    properties (Access = public)
        armLength = 300; % mm of arm length
    end
    
    methods (Access = private)
        
        function sliderValueChanged(app, sliderVal, spinner)
            spinner.Value = sliderVal;            
        end

        function spinnerValueChanged(app, spinnerVal, slider)
            slider.Value = spinnerVal;            
        end

        function results = sendGripperCommand(app, gripperIndex, stateBoolean)
            package = struct; %app.packageStruct;
            package.commandType = "gripper";
            package.index       = gripperIndex;
            package.stateBool   = stateBoolean;
            package.stateFloat  = 0;
            response = webwrite(app.webDestination, package, app.webOptions);
        end

        function results = sendArmMoveCommand(app, armIndex, stateFloat)
            package = struct; %app.packageStruct;
            package.commandType = "arm";
            package.index       = armIndex;
            package.stateBool   = stateFloat;
            package.stateFloat  = stateFloat;
            response = webwrite(app.webDestination, package, app.webOptions);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            limits = [-app.armLength, app.armLength];
            app.ArmPositionSlider_1.Limits = limits;
            app.ArmPositionSlider_2.Limits = limits;
            app.Spinner_1.Limits = app.ArmPositionSlider_1.Limits;
            app.Spinner_2.Limits = app.ArmPositionSlider_2.Limits;

        end

        % Value changed function: ArmPositionSlider_1
        function ArmPositionSlider_1ValueChanged(app, event)
            sliderValueChanged(app, app.ArmPositionSlider_1.Value, app.Spinner_1)
            sendArmMoveCommand(app, 0, app.ArmPositionSlider_1.Value)

        end

        % Value changing function: ArmPositionSlider_1
        function ArmPositionSlider_1ValueChanging(app, event)
             sliderValueChanged(app, event.Value, app.Spinner_1)

        end

        % Value changed function: Spinner_1
        function Spinner_1ValueChanged(app, event)
            spinnerValueChanged(app, app.Spinner_1.Value, app.ArmPositionSlider_1)
            sendArmMoveCommand(app, 0, app.Spinner_1.Value)

        end

        % Value changing function: Spinner_1
        function Spinner_1ValueChanging(app, event)
            spinnerValueChanged(app, event.Value, app.ArmPositionSlider_1)            

        end

        % Selection changed function: GripperStateButtonGroup_1
        function GripperStateButtonGroup_1SelectionChanged(app, event)
            selectedButton = app.GripperStateButtonGroup_1.SelectedObject;
            openBool = strcmpi(selectedButton.Text, "Open");
            sendGripperCommand(app, 0, openBool);

        end

        % Value changed function: ArmPositionSlider_2
        function ArmPositionSlider_2ValueChanged(app, event)
            sliderValueChanged(app, app.ArmPositionSlider_2.Value, app.Spinner_2)
            sendArmMoveCommand(app, 1, app.ArmPositionSlider_2.Value)
        end

        % Value changing function: ArmPositionSlider_2
        function ArmPositionSlider_2ValueChanging(app, event)
             sliderValueChanged(app, event.Value, app.Spinner_2)

        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            spinnerValueChanged(app, app.Spinner_2.Value, app.ArmPositionSlider_2)            
            sendArmMoveCommand(app, 1, app.Spinner_2.Value)

        end

        % Value changing function: Spinner_2
        function Spinner_2ValueChanging(app, event)
            spinnerValueChanged(app, event.Value, app.ArmPositionSlider_2)                        

        end

        % Selection changed function: GripperStateButtonGroup_2
        function GripperStateButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.GripperStateButtonGroup_2.SelectedObject;
            openBool = strcmpi(selectedButton.Text, "Open");
            sendGripperCommand(app, 1, openBool);
            
        end

        % Selection changed function: GripperStateButtonGroup_3
        function GripperStateButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.GripperStateButtonGroup_3.SelectedObject;
            openBool = strcmpi(selectedButton.Text, "Open");
            sendGripperCommand(app, 2, openBool);

        end

        % Selection changed function: GripperStateButtonGroup_4
        function GripperStateButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.GripperStateButtonGroup_4.SelectedObject;
            openBool = strcmpi(selectedButton.Text, "Open");
            sendGripperCommand(app, 3, openBool);

        end

        % Selection changed function: ArmStateButtonGroup
        function ArmStateButtonGroupSelectionChanged(app, event)
            selectedButton = app.ArmStateButtonGroup.SelectedObject;
            state = strcmpi(selectedButton.Text, "Forward");
            if (state ~= 1)
                state = -1*strcmpi(selectedButton.Text, "Backward");
            end
            sendArmMoveCommand(app, 0, state);

        end

        % Selection changed function: ArmStateButtonGroup_2
        function ArmStateButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ArmStateButtonGroup_2.SelectedObject;
            state = strcmpi(selectedButton.Text, "Forward");
            if (state ~= 1)
                state = -1*strcmpi(selectedButton.Text, "Backward");
            end
            sendArmMoveCommand(app, 1, state);
            
        end

        % Selection changed function: ArmStateButtonGroup_3
        function ArmStateButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ArmStateButtonGroup_3.SelectedObject;
            state = strcmpi(selectedButton.Text, "Forward");
            if (state ~= 1)
                state = -1*strcmpi(selectedButton.Text, "Backward");
            end
            state
            sendArmMoveCommand(app, 2, state);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Arm4Panel = uipanel(app.UIFigure);
            app.Arm4Panel.Title = 'Arm 4';
            app.Arm4Panel.Visible = 'off';
            app.GripperStateButtonGroup_4 = uibuttongroup(app.Arm4Panel);
            app.GripperStateButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @GripperStateButtonGroup_4SelectionChanged, true);
            app.GripperStateButtonGroup_4.Title = 'Gripper State';
            app.OpenButton_4 = uitogglebutton(app.GripperStateButtonGroup_4);
            app.OpenButton_4.Text = 'Open';
            app.OpenButton_4.Value = true;
            app.CloseButton_4 = uitogglebutton(app.GripperStateButtonGroup_4);
            app.CloseButton_4.Text = 'Close';
            app.CenterArmArm3Panel = uipanel(app.UIFigure);
            app.CenterArmArm3Panel.Title = 'Center Arm (Arm 3)';
            app.GripperStateButtonGroup_3 = uibuttongroup(app.CenterArmArm3Panel);
            app.GripperStateButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @GripperStateButtonGroup_3SelectionChanged, true);
            app.GripperStateButtonGroup_3.Title = 'Gripper State';
            app.OpenButton_3 = uitogglebutton(app.GripperStateButtonGroup_3);
            app.OpenButton_3.Text = 'Open';
            app.OpenButton_3.Value = true;
            app.CloseButton_3 = uitogglebutton(app.GripperStateButtonGroup_3);
            app.CloseButton_3.Text = 'Close';
            app.ArmStateButtonGroup_3 = uibuttongroup(app.CenterArmArm3Panel);
            app.ArmStateButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ArmStateButtonGroup_3SelectionChanged, true);
            app.ArmStateButtonGroup_3.Title = 'Arm State';
            app.ArmStateButtonGroup_3.Visible = 'off';
            app.BackwardButton_3 = uitogglebutton(app.ArmStateButtonGroup_3);
            app.BackwardButton_3.Text = 'Backward';
            app.BackwardButton_3.Value = true;
            app.StopButton_3 = uitogglebutton(app.ArmStateButtonGroup_3);
            app.StopButton_3.Text = 'Stop';
            app.ForwardButton_3 = uitogglebutton(app.ArmStateButtonGroup_3);
            app.ForwardButton_3.Text = 'Forward';
            app.Spinner_3 = uispinner(app.CenterArmArm3Panel);
            app.ArmPositionSlider_3Label = uilabel(app.CenterArmArm3Panel);
            app.ArmPositionSlider_3Label.Visible = 'off';
            app.ArmPositionSlider_3Label.Text = 'Arm Position';
            app.ArmPositionSlider_3 = uislider(app.CenterArmArm3Panel);
            app.RightArmArm2Panel = uipanel(app.UIFigure);
            app.RightArmArm2Panel.Title = 'Right Arm (Arm 2)';
            app.ArmPositionSlider_2Label = uilabel(app.RightArmArm2Panel);
            app.ArmPositionSlider_2Label.Visible = 'off';
            app.ArmPositionSlider_2Label.Text = 'Arm Position';
            app.ArmPositionSlider_2 = uislider(app.RightArmArm2Panel);
            app.ArmPositionSlider_2.ValueChangedFcn = createCallbackFcn(app, @ArmPositionSlider_2ValueChanged, true);
            app.ArmPositionSlider_2.ValueChangingFcn = createCallbackFcn(app, @ArmPositionSlider_2ValueChanging, true);
            app.Spinner_2 = uispinner(app.RightArmArm2Panel);
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2ValueChanging, true);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.GripperStateButtonGroup_2 = uibuttongroup(app.RightArmArm2Panel);
            app.GripperStateButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @GripperStateButtonGroup_2SelectionChanged, true);
            app.GripperStateButtonGroup_2.Title = 'Gripper State';
            app.OpenButton_2 = uitogglebutton(app.GripperStateButtonGroup_2);
            app.OpenButton_2.Text = 'Open';
            app.OpenButton_2.Value = true;
            app.CloseButton_2 = uitogglebutton(app.GripperStateButtonGroup_2);
            app.CloseButton_2.Text = 'Close';
            app.ArmStateButtonGroup_2 = uibuttongroup(app.RightArmArm2Panel);
            app.ArmStateButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ArmStateButtonGroup_2SelectionChanged, true);
            app.ArmStateButtonGroup_2.Title = 'Arm State';
            app.ArmStateButtonGroup_2.Visible = 'off';
            app.BackwardButton_2 = uitogglebutton(app.ArmStateButtonGroup_2);
            app.BackwardButton_2.Text = 'Backward';
            app.BackwardButton_2.Value = true;
            app.StopButton_2 = uitogglebutton(app.ArmStateButtonGroup_2);
            app.StopButton_2.Text = 'Stop';
            app.ForwardButton_2 = uitogglebutton(app.ArmStateButtonGroup_2);
            app.ForwardButton_2.Text = 'Forward';
            app.LeftArmArm1Panel = uipanel(app.UIFigure);
            app.LeftArmArm1Panel.Title = 'Left Arm (Arm 1)';
            app.ArmPositionSliderLabel = uilabel(app.LeftArmArm1Panel);
            app.ArmPositionSliderLabel.Text = 'Arm Position';
            app.ArmPositionSlider_1 = uislider(app.LeftArmArm1Panel);
            app.ArmPositionSlider_1.ValueChangedFcn = createCallbackFcn(app, @ArmPositionSlider_1ValueChanged, true);
            app.ArmPositionSlider_1.ValueChangingFcn = createCallbackFcn(app, @ArmPositionSlider_1ValueChanging, true);
            app.Spinner_1 = uispinner(app.LeftArmArm1Panel);
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1ValueChanging, true);
            app.Spinner_1.ValueChangedFcn = createCallbackFcn(app, @Spinner_1ValueChanged, true);
            app.GripperStateButtonGroup_1 = uibuttongroup(app.LeftArmArm1Panel);
            app.GripperStateButtonGroup_1.SelectionChangedFcn = createCallbackFcn(app, @GripperStateButtonGroup_1SelectionChanged, true);
            app.GripperStateButtonGroup_1.Title = 'Gripper State';
            app.OpenButton_1 = uitogglebutton(app.GripperStateButtonGroup_1);
            app.OpenButton_1.Text = 'Open';
            app.OpenButton_1.Value = true;
            app.CloseButton_1 = uitogglebutton(app.GripperStateButtonGroup_1);
            app.CloseButton_1.Text = 'Close';
            app.ArmStateButtonGroup = uibuttongroup(app.LeftArmArm1Panel);
            app.ArmStateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ArmStateButtonGroupSelectionChanged, true);
            app.ArmStateButtonGroup.Title = 'Arm State';
            app.ArmStateButtonGroup.Visible = 'off';
            app.BackwardButton = uitogglebutton(app.ArmStateButtonGroup);
            app.BackwardButton.Text = 'Backward';
            app.BackwardButton.Value = true;
            app.StopButton = uitogglebutton(app.ArmStateButtonGroup);
            app.StopButton.Text = 'Stop';
            app.ForwardButton = uitogglebutton(app.ArmStateButtonGroup);
            app.ForwardButton.Text = 'Forward';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robot snapshot.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end